classdef configControlsV4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CONFIGURATIONCONTROLSUIFigure  matlab.ui.Figure
        FillFractionEditField          matlab.ui.control.NumericEditField
        FillFractionEditFieldLabel     matlab.ui.control.Label
        ScanPhaseEditField             matlab.ui.control.NumericEditField
        ScanPhaseLabel                 matlab.ui.control.Label
        MeasureButton                  matlab.ui.control.Button
        FrameRateEditField             matlab.ui.control.NumericEditField
        FrameRateHzLabel               matlab.ui.control.Label
        LinesPerFrameEditField         matlab.ui.control.NumericEditField
        LinesFrameEditFieldLabel       matlab.ui.control.Label
        PixelsPerLineEditField         matlab.ui.control.NumericEditField
        PixelsLineEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Handle
        hPSI    % PolyScanImage
        
        % Data acquisition
        daq_TrigFreq    % NI-DAQ counter terminal to measure line trigger frequency
        
        % Frequency
        trigFreq    % trigger frequency of the polygonal scanner (e.g. 15000Hz = 50000rpm for 18 facet polygonal scanner)
    end
    
    methods (Access = public)
        % Update parameters in this app
        function update(app)
            % Load user settings
            run(app.hPSI.hMain.UserSettingsEditField.Value); 
            
            % Update pixels/line
            app.PixelsPerLineEditField.Value = round(samplesPerSec/app.trigFreq/app.hPSI.hMain.ZoomDropDown.Value); 
            
            % Update frame rate
            app.FrameRateEditField.Value = app.trigFreq /(app.LinesPerFrameEditField.Value/app.hPSI.hMain.ZoomDropDown.Value); 
        end
        
        function updateTriggerFrequency(app)
            % Update line trigger frequency
            warning off
            app.trigFreq = read(app.daq_TrigFreq,'OutputFormat','Matrix'); 
            warning on
            if isnan(app.trigFreq)
                run(app.hPSI.hMain.UserSettingsEditField.Value); 
                app.trigFreq = nominalPolyScanFreq;
                fprintf('Error: Unable to measure trigger frequency, set it to nominal frequency.\n');
            else
                fprintf(['Polygon scanner facet frequency: ',num2str(app.trigFreq),'Hz\n']);
            end
        end
        
        % Exit
        function exitHandle(app)
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hPSI)
            app.hPSI = hPSI; 
            app.CONFIGURATIONCONTROLSUIFigure.Visible = 'off';
            run(app.hPSI.hMain.UserSettingsEditField.Value); 
            
            % Data Acquisition
            fprintf('Connecting NI-DAQ Board for trigger frequency measurement...');
            warning off
            app.daq_TrigFreq = daq('ni'); 
            addinput(app.daq_TrigFreq,triggerFrequencyDeviceName,triggerFrequencyChanID,'Frequency'); 
            warning on
            fprintf('Done!\n');
            app.updateTriggerFrequency
            
            
        end

        % Close request function: CONFIGURATIONCONTROLSUIFigure
        function closeRequest(app, event)
            app.CONFIGURATIONCONTROLSUIFigure.Visible = 'off';

        end

        % Value changed function: LinesPerFrameEditField
        function LinesPerFrameEditFieldValueChanged(app, event)
            app.LinesPerFrameEditField.Value = app.hPSI.hMain.ZoomDropDown.Value*floor(app.LinesPerFrameEditField.Value/app.hPSI.hMain.ZoomDropDown.Value); % Lines/frame must be mutiples of zoom
            app.update;
            app.hPSI.hWfms.update;
            app.hPSI.hImage.update;
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            app.updateTriggerFrequency;
            app.hPSI.updateAll;
        end

        % Value changed function: ScanPhaseEditField
        function ScanPhaseEditFieldValueChanged(app, event)
            app.ScanPhaseEditField.Value = app.ScanPhaseEditField.Value-floor((app.ScanPhaseEditField.Value+179)/360)*360;  % Scan phase is in [-180 180]
        end

        % Value changed function: FillFractionEditField
        function FillFractionEditFieldValueChanged(app, event)
            app.FillFractionEditField.Value = min(app.FillFractionEditField.Value,1);   % Fill fraction smaller than 1
            app.FillFractionEditField.Value = max(app.FillFractionEditField.Value,0);   % Fill fraction larger than 0
            app.FillFractionEditField.Value = round(app.FillFractionEditField.Value*1e4)/1e4;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CONFIGURATIONCONTROLSUIFigure = uifigure('Visible', 'off');
            app.CONFIGURATIONCONTROLSUIFigure.Name = 'CONFIGURATION CONTROLS';
            app.CONFIGURATIONCONTROLSUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.PixelsLineEditFieldLabel = uilabel(app.CONFIGURATIONCONTROLSUIFigure);
            app.PixelsLineEditFieldLabel.Text = 'Pixels / Line';
            app.PixelsPerLineEditField = uieditfield(app.CONFIGURATIONCONTROLSUIFigure, 'numeric');
            app.PixelsPerLineEditField.ValueDisplayFormat = '%.0f';
            app.PixelsPerLineEditField.Editable = 'off';
            app.LinesFrameEditFieldLabel = uilabel(app.CONFIGURATIONCONTROLSUIFigure);
            app.LinesFrameEditFieldLabel.Text = 'Lines / Frame';
            app.LinesPerFrameEditField = uieditfield(app.CONFIGURATIONCONTROLSUIFigure, 'numeric');
            app.LinesPerFrameEditField.ValueChangedFcn = createCallbackFcn(app, @LinesPerFrameEditFieldValueChanged, true);
            app.FrameRateHzLabel = uilabel(app.CONFIGURATIONCONTROLSUIFigure);
            app.FrameRateHzLabel.Text = {'Frame'; 'Rate (Hz)'};
            app.FrameRateEditField = uieditfield(app.CONFIGURATIONCONTROLSUIFigure, 'numeric');
            app.FrameRateEditField.Editable = 'off';
            app.MeasureButton = uibutton(app.CONFIGURATIONCONTROLSUIFigure, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.ScanPhaseLabel = uilabel(app.CONFIGURATIONCONTROLSUIFigure);
            app.ScanPhaseLabel.Text = 'Scan Phase (°)';
            app.ScanPhaseEditField = uieditfield(app.CONFIGURATIONCONTROLSUIFigure, 'numeric');
            app.ScanPhaseEditField.ValueChangedFcn = createCallbackFcn(app, @ScanPhaseEditFieldValueChanged, true);
            app.FillFractionEditFieldLabel = uilabel(app.CONFIGURATIONCONTROLSUIFigure);
            app.FillFractionEditFieldLabel.Text = 'Fill Fraction';
            app.FillFractionEditField = uieditfield(app.CONFIGURATIONCONTROLSUIFigure, 'numeric');
            app.FillFractionEditField.ValueChangedFcn = createCallbackFcn(app, @FillFractionEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.CONFIGURATIONCONTROLSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = configControlsV4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CONFIGURATIONCONTROLSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CONFIGURATIONCONTROLSUIFigure)
        end
    end
end