classdef fastStageControlsV4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FASTSTAGEIMAGINGUIFigure  matlab.ui.Figure
        DeleteButton              matlab.ui.control.Button
        AddButton                 matlab.ui.control.Button
        StagePositionsTable       matlab.ui.control.Table
    end

    
    properties (Access = public)
        % Handle
        hPSI    % PolyScanImage
        
        % Selected stage position
        selectedStagePosition   
        
    end
    
    methods (Access = public)
        % Update parameters in this app
        function update(app)
            run(app.hPSI.hMain.UserSettingsEditField.Value);
            
            data = app.StagePositionsTable.Data; 
            if isempty(data)
                app.hPSI.hWfms.FastStageimagingCheckBox.Value = 0; 
                app.hPSI.hWfms.FastStageimagingCheckBox.Enable = 0;
                app.hPSI.hWfms.update
                return
            end
            app.hPSI.hWfms.FastStageimagingCheckBox.Enable = 1;
            
            % Check if exceed stage moving range
            data(data(:,1) < stageMovingRangeX(1),1) = stageMovingRangeX(1); 
            data(data(:,1) > stageMovingRangeX(2),1) = stageMovingRangeX(2); 
            data(data(:,2) < stageMovingRangeY(1),2) = stageMovingRangeY(1); 
            data(data(:,2) > stageMovingRangeY(2),2) = stageMovingRangeY(2); 
            data(data(:,3) < stageMovingRangeZ(1),3) = stageMovingRangeZ(1); 
            data(data(:,3) > stageMovingRangeZ(2),3) = stageMovingRangeZ(2); 
            
            data(:,1:3) = round(data(:,1:3)*1e4)/1e4;
            data(:,4) = round(data(:,4)); 
            app.StagePositionsTable.Data = data; 
            
            if app.hPSI.hWfms.FastStageimagingCheckBox.Value
                app.hPSI.hWfms.update
            end
        end
        
        % Exit
        function exitHandle(app)
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hPSI)
            app.hPSI = hPSI; 
            app.FASTSTAGEIMAGINGUIFigure.Visible = 'off';
        end

        % Close request function: FASTSTAGEIMAGINGUIFigure
        function closeRequest(app, event)
            app.FASTSTAGEIMAGINGUIFigure.Visible = 'off';
            
        end

        % Cell edit callback: StagePositionsTable
        function StagePositionsTableCellEdit(app, event)
            app.update
        end

        % Cell selection callback: StagePositionsTable
        function StagePositionsTableCellSelection(app, event)
            indices = event.Indices; 
            app.selectedStagePosition = indices(:,1);
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            run(app.hPSI.hMain.UserSettingsEditField.Value);
            if evalin('base',['exist(''',stageHandleName,''',''var'') && ~isempty(',stageHandleName,') && isvalid(',stageHandleName,')'])
                hStage = evalin('base',stageHandleName);
                X = hStage.XEditField.Value;
                Y = hStage.YEditField.Value;
                Z = hStage.ZEditField.Value;
            else
                fprintf(['Error: ''',stageHandleName,''' is not a valid stage handle.\n'])
                X = 0; 
                Y = 0; 
                Z = 0; 
            end
            app.StagePositionsTable.Data = cat(1,app.StagePositionsTable.Data,[X,Y,Z,round(app.hPSI.hConfig.FrameRateEditField.Value),1]);
            app.update
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.StagePositionsTable.Data(app.selectedStagePosition,:) = [];
            app.selectedStagePosition = [];
            app.update
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FASTSTAGEIMAGINGUIFigure = uifigure('Visible', 'off');
            app.FASTSTAGEIMAGINGUIFigure.Name = 'FAST STAGE IMAGING';
            app.FASTSTAGEIMAGINGUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.StagePositionsTable = uitable(app.FASTSTAGEIMAGINGUIFigure);
            app.StagePositionsTable.ColumnName = {'X'; 'Y'; 'Z'; 'Stay Frames'; 'YGalvo Scale'};
            app.StagePositionsTable.RowName = {};
            app.StagePositionsTable.ColumnEditable = true;
            app.StagePositionsTable.CellEditCallback = createCallbackFcn(app, @StagePositionsTableCellEdit, true);
            app.StagePositionsTable.CellSelectionCallback = createCallbackFcn(app, @StagePositionsTableCellSelection, true);
            app.AddButton = uibutton(app.FASTSTAGEIMAGINGUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.FASTSTAGEIMAGINGUIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            % Show the figure after all components are created
            app.FASTSTAGEIMAGINGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fastStageControlsV4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FASTSTAGEIMAGINGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FASTSTAGEIMAGINGUIFigure)
        end
    end
end