classdef imageControlsV4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IMAGECONTROLSUIFigure   matlab.ui.Figure
        Channel2BlackEditField  matlab.ui.control.NumericEditField
        BlackEditFieldLabel_2   matlab.ui.control.Label
        Channel2WhiteEditField  matlab.ui.control.NumericEditField
        WhiteEditFieldLabel_2   matlab.ui.control.Label
        Channel2DispCheckBox    matlab.ui.control.CheckBox
        Channel2SaveCheckBox    matlab.ui.control.CheckBox
        Channel2InvertCheckBox  matlab.ui.control.CheckBox
        Channel2BlackSlider     matlab.ui.control.Slider
        Channel2WhiteSlider     matlab.ui.control.Slider
        Channel2Label           matlab.ui.control.Label
        Channel1DispCheckBox    matlab.ui.control.CheckBox
        Channel1SaveCheckBox    matlab.ui.control.CheckBox
        Channel1InvertCheckBox  matlab.ui.control.CheckBox
        Channel1BlackSlider     matlab.ui.control.Slider
        Channel1WhiteSlider     matlab.ui.control.Slider
        Channel1BlackEditField  matlab.ui.control.NumericEditField
        BlackEditFieldLabel     matlab.ui.control.Label
        Channel1WhiteEditField  matlab.ui.control.NumericEditField
        WhiteEditFieldLabel     matlab.ui.control.Label
        Channel1Label           matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Handle
        hPSI    % PolyScanImage
        
        % Channel mask
        channelMask_Disp    % Channel mask for display
        channelMask_Save    % Channel mask for save
        
        % Channel count
        channelCount_Disp   % Channel count for display
        channelCount_Save   % Channel count for save
        
        % Channel Display
        chanDispFigure 
        chanDispPosition    
    end
    
    methods (Access = public)
        % Update parameters in this app
        function update(app)
            AlazarDefs; 
            run(app.hPSI.hMain.UserSettingsEditField.Value); 
            CHANNEL_1 = eval(Channel1); 
            CHANNEL_2 = eval(Channel2); 
            
            % Update channel mask
            app.channelMask_Disp = app.Channel1DispCheckBox.Value * CHANNEL_1 + app.Channel2DispCheckBox.Value * CHANNEL_2; 
            app.channelMask_Save = app.Channel1SaveCheckBox.Value * CHANNEL_1 + app.Channel2SaveCheckBox.Value * CHANNEL_2; 
            
            % Update channel count
            app.channelCount_Disp = app.Channel1DispCheckBox.Value + app.Channel2DispCheckBox.Value; 
            app.channelCount_Save = app.Channel1SaveCheckBox.Value + app.Channel2SaveCheckBox.Value; 
            
            % Update channel display
            if app.channelCount_Disp > 0
                if app.Channel1DispCheckBox.Value
                    channel = 'CHANNEL 1';
                    white = app.Channel1WhiteEditField.Value;
                    black = app.Channel1BlackEditField.Value;
                else
                    channel = 'CHANNEL 2';
                    white = app.Channel2WhiteEditField.Value;
                    black = app.Channel2BlackEditField.Value;
                end
                pixelsPerLine = app.hPSI.hConfig.PixelsPerLineEditField.Value; 
                linesPerFrame = app.hPSI.hConfig.LinesPerFrameEditField.Value; 
                if isempty(app.chanDispFigure) || ~isvalid(app.chanDispFigure)
                    app.chanDispFigure = figure('NumberTitle','off','Menu','none','ToolBar','none'); 
                    imshow(zeros(linesPerFrame,pixelsPerLine,'uint16')); 
                    if isempty(app.chanDispPosition)
                        app.chanDispPosition = app.chanDispFigure.Position;
                    else 
                        app.chanDispFigure.Position = app.chanDispPosition;
                    end
                end
                app.chanDispPosition = app.chanDispFigure.Position;
                app.chanDispPosition(3) = pixelsPerLine;
                if app.chanDispPosition(4) ~= linesPerFrame
                    app.chanDispPosition(2) = app.chanDispPosition(2)+app.chanDispPosition(4)-linesPerFrame;
                    app.chanDispPosition(4) = linesPerFrame; 
                end
                app.chanDispFigure.Position = app.chanDispPosition; 
                app.chanDispFigure.CurrentAxes.Position = [0,0,1,1];
                app.chanDispFigure.Name = channel; 
                app.chanDispFigure.Children.XLim = [1,pixelsPerLine]; 
                app.chanDispFigure.Children.YLim = [1,linesPerFrame]; 
                app.chanDispFigure.Children.CLim = [black,white]; 
                app.chanDispFigure.Children.Children.CData = zeros(linesPerFrame,pixelsPerLine,'uint16');
            else
                if ~isempty(app.chanDispFigure) && isvalid(app.chanDispFigure)
                    close(app.chanDispFigure); 
                end
            end
        end
        
        % Exit
        function exitHandle(app)
            delete(app.chanDispFigure);
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hPSI)
            app.hPSI = hPSI; 
            app.IMAGECONTROLSUIFigure.Visible = 'off';
        end

        % Close request function: IMAGECONTROLSUIFigure
        function closeRequest(app, event)
            app.IMAGECONTROLSUIFigure.Visible = 'off';

        end

        % Value changed function: Channel1WhiteEditField
        function Channel1WhiteEditFieldValueChanged(app, event)
            if app.Channel1WhiteEditField.Value < app.Channel1BlackEditField.Value
                app.Channel1WhiteEditField.Value = app.Channel1BlackEditField.Value;
            end
            app.Channel1WhiteSlider.Value = app.Channel1WhiteEditField.Value;
            
        end

        % Value changed function: Channel1BlackEditField
        function Channel1BlackEditFieldValueChanged(app, event)
            if app.Channel1BlackEditField.Value > app.Channel1WhiteEditField.Value
                app.Channel1BlackEditField.Value = app.Channel1WhiteEditField.Value;
            end
            app.Channel1BlackSlider.Value = app.Channel1BlackEditField.Value;
            
        end

        % Value changed function: Channel1WhiteSlider
        function Channel1WhiteSliderValueChanged(app, event)
            if app.Channel1WhiteSlider.Value < app.Channel1BlackSlider.Value
                app.Channel1WhiteSlider.Value = app.Channel1BlackSlider.Value;
            end
            app.Channel1WhiteEditField.Value = app.Channel1WhiteSlider.Value;
            
        end

        % Value changed function: Channel1BlackSlider
        function Channel1BlackSliderValueChanged(app, event)
            if app.Channel1BlackSlider.Value > app.Channel1WhiteSlider.Value
                app.Channel1BlackSlider.Value = app.Channel1WhiteSlider.Value;
            end
            app.Channel1BlackEditField.Value = app.Channel1BlackSlider.Value;
            
        end

        % Value changed function: Channel1SaveCheckBox
        function Channel1SaveCheckBoxValueChanged(app, event)
            app.update
            
        end

        % Value changed function: Channel1DispCheckBox
        function Channel1DispCheckBoxValueChanged(app, event)
            if app.Channel1DispCheckBox.Value
                app.Channel2DispCheckBox.Value = 0;
                drawnow
            end
            app.update
        end

        % Value changed function: Channel2WhiteEditField
        function Channel2WhiteEditFieldValueChanged(app, event)
            if app.Channel2WhiteEditField.Value < app.Channel2BlackEditField.Value
                app.Channel2WhiteEditField.Value = app.Channel2BlackEditField.Value;
            end
            app.Channel2WhiteSlider.Value = app.Channel2WhiteEditField.Value;
        end

        % Value changed function: Channel2WhiteSlider
        function Channel2WhiteSliderValueChanged(app, event)
            if app.Channel2WhiteSlider.Value < app.Channel2BlackSlider.Value
                app.Channel2WhiteSlider.Value = app.Channel2BlackSlider.Value;
            end
            app.Channel2WhiteEditField.Value = app.Channel2WhiteSlider.Value;
        end

        % Value changed function: Channel2BlackEditField
        function Channel2BlackEditFieldValueChanged(app, event)
            if app.Channel2BlackEditField.Value > app.Channel2WhiteEditField.Value
                app.Channel2BlackEditField.Value = app.Channel2WhiteEditField.Value;
            end
            app.Channel2BlackSlider.Value = app.Channel2BlackEditField.Value;
        end

        % Value changed function: Channel2BlackSlider
        function Channel2BlackSliderValueChanged(app, event)
            if app.Channel2BlackSlider.Value > app.Channel2WhiteSlider.Value
                app.Channel2BlackSlider.Value = app.Channel2WhiteSlider.Value;
            end
            app.Channel2BlackEditField.Value = app.Channel2BlackSlider.Value;
        end

        % Value changed function: Channel2SaveCheckBox
        function Channel2SaveCheckBoxValueChanged(app, event)
            app.update
            
        end

        % Value changed function: Channel2DispCheckBox
        function Channel2DispCheckBoxValueChanged(app, event)
            if app.Channel2DispCheckBox.Value
                app.Channel1DispCheckBox.Value = 0;
                drawnow
            end
            app.update
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IMAGECONTROLSUIFigure = uifigure('Visible', 'off');
            app.IMAGECONTROLSUIFigure.Name = 'IMAGE CONTROLS';
            app.IMAGECONTROLSUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.Channel1Label = uilabel(app.IMAGECONTROLSUIFigure);
            app.Channel1Label.Text = 'Channel 1';
            app.WhiteEditFieldLabel = uilabel(app.IMAGECONTROLSUIFigure);
            app.WhiteEditFieldLabel.Text = 'White';
            app.Channel1WhiteEditField = uieditfield(app.IMAGECONTROLSUIFigure, 'numeric');
            app.Channel1WhiteEditField.Limits = [0 32768];
            app.Channel1WhiteEditField.RoundFractionalValues = 'on';
            app.Channel1WhiteEditField.ValueDisplayFormat = '%.0f';
            app.Channel1WhiteEditField.ValueChangedFcn = createCallbackFcn(app, @Channel1WhiteEditFieldValueChanged, true);
            app.Channel1WhiteEditField.Value = 32768;
            app.BlackEditFieldLabel = uilabel(app.IMAGECONTROLSUIFigure);
            app.BlackEditFieldLabel.Text = 'Black';
            app.Channel1BlackEditField = uieditfield(app.IMAGECONTROLSUIFigure, 'numeric');
            app.Channel1BlackEditField.Limits = [0 32768];
            app.Channel1BlackEditField.RoundFractionalValues = 'on';
            app.Channel1BlackEditField.ValueDisplayFormat = '%.0f';
            app.Channel1BlackEditField.ValueChangedFcn = createCallbackFcn(app, @Channel1BlackEditFieldValueChanged, true);
            app.Channel1WhiteSlider = uislider(app.IMAGECONTROLSUIFigure);
            app.Channel1WhiteSlider.Limits = [0 32768];
            app.Channel1WhiteSlider.MajorTicks = [];
            app.Channel1WhiteSlider.ValueChangedFcn = createCallbackFcn(app, @Channel1WhiteSliderValueChanged, true);
            app.Channel1WhiteSlider.MinorTicks = [];
            app.Channel1WhiteSlider.Value = 32768;
            app.Channel1BlackSlider = uislider(app.IMAGECONTROLSUIFigure);
            app.Channel1BlackSlider.Limits = [0 32768];
            app.Channel1BlackSlider.MajorTicks = [];
            app.Channel1BlackSlider.ValueChangedFcn = createCallbackFcn(app, @Channel1BlackSliderValueChanged, true);
            app.Channel1BlackSlider.MinorTicks = [];
            app.Channel1InvertCheckBox = uicheckbox(app.IMAGECONTROLSUIFigure);
            app.Channel1InvertCheckBox.Text = 'Invert';
            app.Channel1SaveCheckBox = uicheckbox(app.IMAGECONTROLSUIFigure);
            app.Channel1SaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @Channel1SaveCheckBoxValueChanged, true);
            app.Channel1SaveCheckBox.Text = 'Save';
            app.Channel1DispCheckBox = uicheckbox(app.IMAGECONTROLSUIFigure);
            app.Channel1DispCheckBox.ValueChangedFcn = createCallbackFcn(app, @Channel1DispCheckBoxValueChanged, true);
            app.Channel1DispCheckBox.Text = 'Disp';
            app.Channel2Label = uilabel(app.IMAGECONTROLSUIFigure);
            app.Channel2Label.Text = 'Channel 2';
            app.Channel2WhiteSlider = uislider(app.IMAGECONTROLSUIFigure);
            app.Channel2WhiteSlider.Limits = [0 32768];
            app.Channel2WhiteSlider.MajorTicks = [];
            app.Channel2WhiteSlider.ValueChangedFcn = createCallbackFcn(app, @Channel2WhiteSliderValueChanged, true);
            app.Channel2WhiteSlider.MinorTicks = [];
            app.Channel2WhiteSlider.Value = 32768;
            app.Channel2BlackSlider = uislider(app.IMAGECONTROLSUIFigure);
            app.Channel2BlackSlider.Limits = [0 32768];
            app.Channel2BlackSlider.MajorTicks = [];
            app.Channel2BlackSlider.ValueChangedFcn = createCallbackFcn(app, @Channel2BlackSliderValueChanged, true);
            app.Channel2BlackSlider.MinorTicks = [];
            app.Channel2InvertCheckBox = uicheckbox(app.IMAGECONTROLSUIFigure);
            app.Channel2InvertCheckBox.Text = 'Invert';
            app.Channel2SaveCheckBox = uicheckbox(app.IMAGECONTROLSUIFigure);
            app.Channel2SaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @Channel2SaveCheckBoxValueChanged, true);
            app.Channel2SaveCheckBox.Text = 'Save';
            app.Channel2DispCheckBox = uicheckbox(app.IMAGECONTROLSUIFigure);
            app.Channel2DispCheckBox.ValueChangedFcn = createCallbackFcn(app, @Channel2DispCheckBoxValueChanged, true);
            app.Channel2DispCheckBox.Text = 'Disp';
            app.WhiteEditFieldLabel_2 = uilabel(app.IMAGECONTROLSUIFigure);
            app.WhiteEditFieldLabel_2.Text = 'White';
            app.Channel2WhiteEditField = uieditfield(app.IMAGECONTROLSUIFigure, 'numeric');
            app.Channel2WhiteEditField.Limits = [0 32768];
            app.Channel2WhiteEditField.RoundFractionalValues = 'on';
            app.Channel2WhiteEditField.ValueDisplayFormat = '%.0f';
            app.Channel2WhiteEditField.ValueChangedFcn = createCallbackFcn(app, @Channel2WhiteEditFieldValueChanged, true);
            app.Channel2WhiteEditField.Value = 32768;
            app.BlackEditFieldLabel_2 = uilabel(app.IMAGECONTROLSUIFigure);
            app.BlackEditFieldLabel_2.Text = 'Black';
            app.Channel2BlackEditField = uieditfield(app.IMAGECONTROLSUIFigure, 'numeric');
            app.Channel2BlackEditField.Limits = [0 32768];
            app.Channel2BlackEditField.RoundFractionalValues = 'on';
            app.Channel2BlackEditField.ValueDisplayFormat = '%.0f';
            app.Channel2BlackEditField.ValueChangedFcn = createCallbackFcn(app, @Channel2BlackEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.IMAGECONTROLSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageControlsV4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IMAGECONTROLSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IMAGECONTROLSUIFigure)
        end
    end
end