classdef mainControlsV4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MAINCONTROLSUIFigure         matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        MachineConfigurationMenu     matlab.ui.container.Menu
        ExitPolyScanImageMenu        matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        BeamMenu                     matlab.ui.container.Menu
        WaveformMenu                 matlab.ui.container.Menu
        VolumeMenu                   matlab.ui.container.Menu
        FastStageMenu                matlab.ui.container.Menu
        StimulationMenu              matlab.ui.container.Menu
        ViewMenu                     matlab.ui.container.Menu
        ImageControlsMenu            matlab.ui.container.Menu
        Channel1DisplayMenu          matlab.ui.container.Menu
        Channel2DisplayMenu          matlab.ui.container.Menu
        TotalStagePositionEditField  matlab.ui.control.NumericEditField
        ofEditFieldLabel_2           matlab.ui.control.Label
        StagePositionEditField       matlab.ui.control.NumericEditField
        StagePositionEditFieldLabel  matlab.ui.control.Label
        ConfigButton                 matlab.ui.control.Button
        LOOPButton                   matlab.ui.control.StateButton
        GRABButton                   matlab.ui.control.StateButton
        FOCUSButton                  matlab.ui.control.StateButton
        idleLabel                    matlab.ui.control.Label
        DataLoggingPanel             matlab.ui.container.Panel
        saveCheckBox                 matlab.ui.control.CheckBox
        AvgEditField                 matlab.ui.control.NumericEditField
        AvgLabel                     matlab.ui.control.Label
        FramesPerFileEditField       matlab.ui.control.NumericEditField
        FramesFileEditFieldLabel     matlab.ui.control.Label
        AcquisitionEditField         matlab.ui.control.NumericEditField
        AcquisitionLabel             matlab.ui.control.Label
        DIRButton                    matlab.ui.control.Button
        BasenameEditField            matlab.ui.control.EditField
        BasenameEditFieldLabel       matlab.ui.control.Label
        TotalFrameEditField          matlab.ui.control.NumericEditField
        ofEditFieldLabel             matlab.ui.control.Label
        FramesDoneEditField          matlab.ui.control.NumericEditField
        FramesDoneEditFieldLabel     matlab.ui.control.Label
        ZoomDropDown                 matlab.ui.control.DropDown
        ZoomDropDownLabel            matlab.ui.control.Label
        ConfigurationSaveButton      matlab.ui.control.Button
        ConfigurationLoadButton      matlab.ui.control.Button
        UserSettingsSaveButton       matlab.ui.control.Button
        UserSettingsLoadButton       matlab.ui.control.Button
        ConfigurationEditField       matlab.ui.control.EditField
        ConfigurationEditFieldLabel  matlab.ui.control.Label
        UserSettingsEditField        matlab.ui.control.EditField
        UserSettingsEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Handle
        hPSI        % PolyScanImage
        hBoard      % Alazar DAQ board
        hChannel    % Channel display window
        
        % Path
        path_Data   % Path of folder to save captured data file
        
        % DAQ offset
        offset;
    end
    
    methods (Access = public)
        % Update parameters in this app
        function update(app)
            % Update path of captured data
            run(app.UserSettingsEditField.Value)
            if isempty(app.path_Data)
                app.path_Data = dataDir;
            end
            
            % Update acquisition num
            basename = app.BasenameEditField.Value;
            acqNum = app.AcquisitionEditField.Value;
            while exist([app.path_Data,basename,'_',num2str(acqNum,'%05d'),'.tif'],'file')
                acqNum = acqNum + 1; 
            end
            app.AcquisitionEditField.Value = acqNum; 
        end
        
        % Exit
        function exitHandle(app)
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hPSI)
            app.hPSI = hPSI;
            app.MAINCONTROLSUIFigure.Visible = 'off';
            
            % Load user settings
            fprintf('Loading user settings...')
            app.UserSettingsEditField.Value = which('MachineConfiguration_PSI.m'); 
            if isempty(app.UserSettingsEditField.Value)
                fprintf('\nError: Cannot find user settings file.\n'); 
                delete(app);
                return
            end
            run(app.UserSettingsEditField.Value); 
            fprintf('Done!\n'); 
            addpath(genpath(mainDir)); 
            
            % Connect Alazar DAQ board
            fprintf('Connecting Alazar DAQ Board...'); 
            app.hBoard = getBoardHandle(systemId,boardId); 
            fprintf('Done!\n'); 
            app.offset = daqOffset(app);
            fprintf(['DAQ offset: ',num2str(app.offset-2^15),'\n']);
            app.hBoard = getBoardHandle(systemId,boardId); 
        end

        % Close request function: MAINCONTROLSUIFigure
        function closeRequest(app, event)
            selection = uiconfirm(app.MAINCONTROLSUIFigure, ...
                'Are you sure you want to exit PolyScanImage?', ...
                'Exit PolyScanImage Confirmation', 'Icon','question', ...
                "Options",{'Yes','No'});
            if strcmp(selection,'Yes')
                app.hPSI.closeRequest;
            end
            
        end

        % Button pushed function: UserSettingsLoadButton
        function UserSettingsLoadButtonPushed(app, event)
            [file,path] = uigetfile(app.UserSettingsEditField.value);
            if file
                app.UserSettingsEditField.Value = [path,file];
            end
        end

        % Button pushed function: UserSettingsSaveButton
        function UserSettingsSaveButtonPushed(app, event)
            
        end

        % Button pushed function: ConfigurationLoadButton
        function ConfigurationLoadButtonPushed(app, event)
            [file,path] = uigetfile(app.ConfigurationEditField.Value);
            if ~file
                return
            end
            app.ConfigurationEditField.Value = [path,file];
            loadConfiguration_PSI(app.hPSI,app.ConfigurationEditField.Value); 
            app.hPSI.updateAll; 
        end

        % Button pushed function: ConfigurationSaveButton
        function ConfigurationSaveButtonPushed(app, event)
            saveConfiguration_PSI(app.hPSI,app.ConfigurationEditField.Value);
        end

        % Value changed function: ZoomDropDown
        function ZoomDropDownValueChanged(app, event)
            app.hPSI.updateAll;
            
        end

        % Value changed function: BasenameEditField
        function BasenameEditFieldValueChanged(app, event)
            app.AcquisitionEditField.Value = 1; 
            app.update
            
        end

        % Button pushed function: DIRButton
        function DIRButtonPushed(app, event)
            app.update
            path = uigetdir(app.path_Data);
            if path
                app.path_Data = [path,'\'];
            end
            app.update
        end

        % Value changed function: AcquisitionEditField
        function AcquisitionEditFieldValueChanged(app, event)
            app.update
        end

        % Value changed function: FOCUSButton
        function FOCUSButtonValueChanged(app, event)
            if app.FOCUSButton.Value
                app.FOCUSButton.Text = 'Abort';
                app.GRABButton.Visible = false;
                app.LOOPButton.Visible = false;
                drawnow
                focus_PSI(app.hPSI)
            end
            
        end

        % Value changed function: GRABButton
        function GRABButtonValueChanged(app, event)
            if app.GRABButton.Value
                app.GRABButton.Text = 'Abort';
                app.FOCUSButton.Visible = false;
                app.LOOPButton.Visible = false;
                drawnow
                grab_PSI(app.hPSI)
                app.update
            end
            
        end

        % Value changed function: LOOPButton
        function LOOPButtonValueChanged(app, event)
            value = app.LOOPButton.Value;
            
        end

        % Button pushed function: ConfigButton
        function ConfigButtonPushed(app, event)
            app.hPSI.hConfig.CONFIGURATIONCONTROLSUIFigure.Visible = 'on';
        end

        % Menu selected function: BeamMenu
        function BeamMenuSelected(app, event)
            app.hPSI.hBeams.POWERCONTROLSUIFigure.Visible = 'on';
        end

        % Menu selected function: WaveformMenu
        function WaveformMenuSelected(app, event)
            app.hPSI.hWfms.WAVEFORMCONTROLSUIFigure.Visible = 'on';
        end

        % Menu selected function: VolumeMenu
        function VolumeMenuSelected(app, event)
            
        end

        % Menu selected function: FastStageMenu
        function FastStageMenuSelected(app, event)
            app.hPSI.hFastStage.FASTSTAGEIMAGINGUIFigure.Visible = 'on';
        end

        % Menu selected function: StimulationMenu
        function StimulationMenuSelected(app, event)
            app.hPSI.hStimulate.STIMULATIONCONTROLUIFigure.Visible = 'on';
        end

        % Menu selected function: ImageControlsMenu
        function ImageControlsMenuSelected(app, event)
            app.hPSI.hImage.IMAGECONTROLSUIFigure.Visible = 'on';
        end

        % Menu selected function: Channel1DisplayMenu
        function Channel1DisplayMenuSelected(app, event)
            app.hPSI.hImage.Channel1DispCheckBox = 1;
            app.hPSI.hImage.Channel2DispCheckBox = 0;
            app.hPSI.hImage.update;
            
        end

        % Menu selected function: Channel2DisplayMenu
        function Channel2DisplayMenuSelected(app, event)
            app.hPSI.hImage.Channel1DispCheckBox = 0;
            app.hPSI.hImage.Channel2DispCheckBox = 1;
            app.hPSI.hImage.update;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MAINCONTROLSUIFigure = uifigure('Visible', 'off');
            app.MAINCONTROLSUIFigure.Name = 'MAIN CONTROLS';
            app.MAINCONTROLSUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.FileMenu = uimenu(app.MAINCONTROLSUIFigure);
            app.FileMenu.Text = 'File';
            app.MachineConfigurationMenu = uimenu(app.FileMenu);
            app.MachineConfigurationMenu.Text = 'Machine Configuration';
            app.ExitPolyScanImageMenu = uimenu(app.FileMenu);
            app.ExitPolyScanImageMenu.Text = 'Exit PolyScanImage';
            app.SettingsMenu = uimenu(app.MAINCONTROLSUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.BeamMenu = uimenu(app.SettingsMenu);
            app.BeamMenu.MenuSelectedFcn = createCallbackFcn(app, @BeamMenuSelected, true);
            app.BeamMenu.Text = 'Beam';
            app.WaveformMenu = uimenu(app.SettingsMenu);
            app.WaveformMenu.MenuSelectedFcn = createCallbackFcn(app, @WaveformMenuSelected, true);
            app.WaveformMenu.Text = 'Waveform';
            app.VolumeMenu = uimenu(app.SettingsMenu);
            app.VolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @VolumeMenuSelected, true);
            app.VolumeMenu.Text = 'Volume';
            app.FastStageMenu = uimenu(app.SettingsMenu);
            app.FastStageMenu.MenuSelectedFcn = createCallbackFcn(app, @FastStageMenuSelected, true);
            app.FastStageMenu.Text = 'Fast Stage';
            app.StimulationMenu = uimenu(app.SettingsMenu);
            app.StimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @StimulationMenuSelected, true);
            app.StimulationMenu.Text = 'Stimulation';
            app.ViewMenu = uimenu(app.MAINCONTROLSUIFigure);
            app.ViewMenu.Text = 'View';
            app.ImageControlsMenu = uimenu(app.ViewMenu);
            app.ImageControlsMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageControlsMenuSelected, true);
            app.ImageControlsMenu.Text = 'Image Controls';
            app.Channel1DisplayMenu = uimenu(app.ViewMenu);
            app.Channel1DisplayMenu.MenuSelectedFcn = createCallbackFcn(app, @Channel1DisplayMenuSelected, true);
            app.Channel1DisplayMenu.Text = 'Channel 1 Display';
            app.Channel2DisplayMenu = uimenu(app.ViewMenu);
            app.Channel2DisplayMenu.MenuSelectedFcn = createCallbackFcn(app, @Channel2DisplayMenuSelected, true);
            app.Channel2DisplayMenu.Text = 'Channel 2 Display';
            app.UserSettingsEditFieldLabel = uilabel(app.MAINCONTROLSUIFigure);
            app.UserSettingsEditFieldLabel.Text = 'User Settings';
            app.UserSettingsEditField = uieditfield(app.MAINCONTROLSUIFigure, 'text');
            app.ConfigurationEditFieldLabel = uilabel(app.MAINCONTROLSUIFigure);
            app.ConfigurationEditFieldLabel.Text = 'Configuration';
            app.ConfigurationEditField = uieditfield(app.MAINCONTROLSUIFigure, 'text');
            app.UserSettingsLoadButton = uibutton(app.MAINCONTROLSUIFigure, 'push');
            app.UserSettingsLoadButton.ButtonPushedFcn = createCallbackFcn(app, @UserSettingsLoadButtonPushed, true);
            app.UserSettingsLoadButton.Text = 'load';
            app.UserSettingsSaveButton = uibutton(app.MAINCONTROLSUIFigure, 'push');
            app.UserSettingsSaveButton.ButtonPushedFcn = createCallbackFcn(app, @UserSettingsSaveButtonPushed, true);
            app.UserSettingsSaveButton.Enable = 'off';
            app.UserSettingsSaveButton.Text = 'save';
            app.ConfigurationLoadButton = uibutton(app.MAINCONTROLSUIFigure, 'push');
            app.ConfigurationLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurationLoadButtonPushed, true);
            app.ConfigurationLoadButton.Text = 'load';
            app.ConfigurationSaveButton = uibutton(app.MAINCONTROLSUIFigure, 'push');
            app.ConfigurationSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurationSaveButtonPushed, true);
            app.ConfigurationSaveButton.Text = 'save';
            app.ZoomDropDownLabel = uilabel(app.MAINCONTROLSUIFigure);
            app.ZoomDropDownLabel.Text = 'Zoom';
            app.ZoomDropDown = uidropdown(app.MAINCONTROLSUIFigure);
            app.ZoomDropDown.Items = {'1', '2', '4', '8'};
            app.ZoomDropDown.ItemsData = [1 2 4 8];
            app.ZoomDropDown.ValueChangedFcn = createCallbackFcn(app, @ZoomDropDownValueChanged, true);
            app.ZoomDropDown.Value = 1;
            app.FramesDoneEditFieldLabel = uilabel(app.MAINCONTROLSUIFigure);
            app.FramesDoneEditFieldLabel.Text = 'Frames Done';
            app.FramesDoneEditField = uieditfield(app.MAINCONTROLSUIFigure, 'numeric');
            app.FramesDoneEditField.ValueDisplayFormat = '%.0f';
            app.ofEditFieldLabel = uilabel(app.MAINCONTROLSUIFigure);
            app.ofEditFieldLabel.Text = 'of';
            app.TotalFrameEditField = uieditfield(app.MAINCONTROLSUIFigure, 'numeric');
            app.TotalFrameEditField.Limits = [1 Inf];
            app.TotalFrameEditField.RoundFractionalValues = 'on';
            app.TotalFrameEditField.ValueDisplayFormat = '%.0f';
            app.TotalFrameEditField.Value = 1;
            app.DataLoggingPanel = uipanel(app.MAINCONTROLSUIFigure);
            app.DataLoggingPanel.Title = 'Data Logging';
            app.BasenameEditFieldLabel = uilabel(app.DataLoggingPanel);
            app.BasenameEditFieldLabel.Text = 'Basename';
            app.BasenameEditField = uieditfield(app.DataLoggingPanel, 'text');
            app.BasenameEditField.ValueChangedFcn = createCallbackFcn(app, @BasenameEditFieldValueChanged, true);
            app.DIRButton = uibutton(app.DataLoggingPanel, 'push');
            app.DIRButton.ButtonPushedFcn = createCallbackFcn(app, @DIRButtonPushed, true);
            app.DIRButton.Text = 'DIR...';
            app.AcquisitionLabel = uilabel(app.DataLoggingPanel);
            app.AcquisitionLabel.Text = 'Acquisition #';
            app.AcquisitionEditField = uieditfield(app.DataLoggingPanel, 'numeric');
            app.AcquisitionEditField.Limits = [1 Inf];
            app.AcquisitionEditField.RoundFractionalValues = 'on';
            app.AcquisitionEditField.ValueDisplayFormat = '%.0f';
            app.AcquisitionEditField.ValueChangedFcn = createCallbackFcn(app, @AcquisitionEditFieldValueChanged, true);
            app.AcquisitionEditField.Value = 1;
            app.FramesFileEditFieldLabel = uilabel(app.DataLoggingPanel);
            app.FramesFileEditFieldLabel.Text = '# Frames/File';
            app.FramesPerFileEditField = uieditfield(app.DataLoggingPanel, 'numeric');
            app.FramesPerFileEditField.Limits = [1 Inf];
            app.FramesPerFileEditField.RoundFractionalValues = 'on';
            app.FramesPerFileEditField.ValueDisplayFormat = '%.0f';
            app.FramesPerFileEditField.Value = Inf;
            app.AvgLabel = uilabel(app.DataLoggingPanel);
            app.AvgLabel.Text = '# Avg';
            app.AvgEditField = uieditfield(app.DataLoggingPanel, 'numeric');
            app.AvgEditField.Limits = [1 Inf];
            app.AvgEditField.RoundFractionalValues = 'on';
            app.AvgEditField.ValueDisplayFormat = '%.0f';
            app.AvgEditField.Value = 1;
            app.saveCheckBox = uicheckbox(app.DataLoggingPanel);
            app.saveCheckBox.Text = 'save';
            app.saveCheckBox.Value = true;
            app.idleLabel = uilabel(app.MAINCONTROLSUIFigure);
            app.idleLabel.Text = 'idle';
            app.FOCUSButton = uibutton(app.MAINCONTROLSUIFigure, 'state');
            app.FOCUSButton.ValueChangedFcn = createCallbackFcn(app, @FOCUSButtonValueChanged, true);
            app.FOCUSButton.Text = 'FOCUS';
            app.GRABButton = uibutton(app.MAINCONTROLSUIFigure, 'state');
            app.GRABButton.ValueChangedFcn = createCallbackFcn(app, @GRABButtonValueChanged, true);
            app.GRABButton.Text = 'GRAB';
            app.LOOPButton = uibutton(app.MAINCONTROLSUIFigure, 'state');
            app.LOOPButton.ValueChangedFcn = createCallbackFcn(app, @LOOPButtonValueChanged, true);
            app.LOOPButton.Enable = 'off';
            app.LOOPButton.Text = 'LOOP';
            app.ConfigButton = uibutton(app.MAINCONTROLSUIFigure, 'push');
            app.ConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigButtonPushed, true);
            app.ConfigButton.Text = 'Config >>';
            app.StagePositionEditFieldLabel = uilabel(app.MAINCONTROLSUIFigure);
            app.StagePositionEditFieldLabel.Text = 'Stage Position';
            app.StagePositionEditField = uieditfield(app.MAINCONTROLSUIFigure, 'numeric');
            app.StagePositionEditField.ValueDisplayFormat = '%.0f';
            app.StagePositionEditField.Value = 1;
            app.ofEditFieldLabel_2 = uilabel(app.MAINCONTROLSUIFigure);
            app.ofEditFieldLabel_2.Text = 'of';
            app.TotalStagePositionEditField = uieditfield(app.MAINCONTROLSUIFigure, 'numeric');
            app.TotalStagePositionEditField.Limits = [1 Inf];
            app.TotalStagePositionEditField.RoundFractionalValues = 'on';
            app.TotalStagePositionEditField.ValueDisplayFormat = '%.0f';
            app.TotalStagePositionEditField.Value = 1;
            % Show the figure after all components are created
            app.MAINCONTROLSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainControlsV4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MAINCONTROLSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MAINCONTROLSUIFigure)
        end
    end
end