classdef powerControlsV4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        POWERCONTROLSUIFigure  matlab.ui.Figure
        ShowCurveButton        matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        CalibrateButton        matlab.ui.control.Button
        PowerEditField         matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        PowerSlider            matlab.ui.control.Slider
        SelectedBeamLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Handle
        hPSI    % PolyScanImage
        
        % Data acquisition
        daq_Shutter % NI-DAQ channels to control shutter
        daq_Beam    % NI-DAQ channels to modulate and calibrate power of beam
        
        % Path
        path_CalData    % Path of calibration data
        
        % Beam modulation
        calData     % Calibration data
        modVoltage  % Modulation Voltage
        
    end
    
    methods (Access = public)
        % Update parameters in this app
        function update(app)
            power = app.PowerEditField.Value / 100;
            range = find(app.calData(:,1) >= power,1,'first'); 
            if range == 1
                app.modVoltage = app.calData(1,2);
                power = app.calData(1,1);
            elseif isempty(range)
                app.modVoltage = app.calData(end,2);
                power = app.calData(end,1);
            else
                range = [range - 1, range]; 
                app.modVoltage = (power-app.calData(range(1),1))/(app.calData(range(2),1)-app.calData(range(1),1))*app.calData(range(2),2) ...
                                +(power-app.calData(range(2),1))/(app.calData(range(1),1)-app.calData(range(2),1))*app.calData(range(1),2); 
            end
            
            power = round(power*1e4)/1e4;
            app.PowerEditField.Value = 100 * power;     % Unit(%)
            app.PowerSlider.Value = 100 * power;        % Unit(%)
        end
        
        % Start output laser beam
        function startOutputBeam(app)
            run(app.hPSI.hMain.UserSettingsEditField.Value); 
            write(app.daq_Beam,app.modVoltage); 
            write(app.daq_Shutter,1); pause(shutterOpenTime)
            
        end
        
        % Stop output laser beam
        function stopOutputBeam(app)
            run(app.hPSI.hMain.UserSettingsEditField.Value); 
            write(app.daq_Beam,beamModVoltageRange(1)); 
            write(app.daq_Shutter,0); 
        end
        
        % Exit
        function exitHandle(app)
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hPSI)
            app.hPSI = hPSI; 
            app.POWERCONTROLSUIFigure.Visible = 'off';
            run(app.hPSI.hMain.UserSettingsEditField.Value); 
            
            % Data Acquisition
            warning off
            %   Shutter
            fprintf('Connecting NI-DAQ Board for beam modulation...');
            app.daq_Shutter = daq('ni');
            addoutput(app.daq_Shutter,shutterDaqDevice,shutterChannelID,'Digital');                    % Shutter control
            %   Beam modulation
            app.daq_Beam = daq('ni');
            addoutput(app.daq_Beam,beamModDevices,['ao',num2str(beamModChanID)],'Voltage');         % Beam power modulation voltage output
            addinput(app.daq_Beam,beamModDevices,['ai',num2str(beamModCalInputChanID)],'Voltage');  % Beam power modulation calibration input
            warning on
            fprintf('Done!\n');
            
            % Load default beam calibration data
            if ~isempty(beamCalData) && ~isempty(which(beamCalData))
                app.path_CalData = which(beamCalData); 
                app.calData = struct2cell(load(app.path_CalData)); 
                app.calData = app.calData{1}; 
            else
                fprintf('Default beam calibration data do not exist, please calibrate beam modulation data before DAQ.\n');
                app.calData = [linspace(0,1,100).', linspace(beamModVoltageRange(1),beamModVoltageRange(2),100).'];    % Default linear modulation
            end
            
        end

        % Close request function: POWERCONTROLSUIFigure
        function closeRequest(app, event)
            app.POWERCONTROLSUIFigure.Visible = 'off';
            
        end

        % Value changed function: PowerEditField
        function PowerEditFieldValueChanged(app, event)
            app.PowerEditField.Value = round(app.PowerEditField.Value*1e2)/1e2;
            app.PowerSlider.Value = app.PowerEditField.Value;
            app.update
        end

        % Value changed function: PowerSlider
        function PowerSliderValueChanged(app, event)
            app.PowerSlider.Value = round(app.PowerSlider.Value*1e2)/1e2; 
            app.PowerEditField.Value = app.PowerSlider.Value; 
            app.update
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            run(app.hPSI.hMain.UserSettingsEditField.Value); 
            
            fprintf('Calibrating beam modulation data...')
            write(app.daq_Shutter,1);
            pause(shutterOpenTime);
            
            voltage = linspace(beamModVoltageRange(1),beamModVoltageRange(2),100).';
            power = 0 * voltage;
            for k = 1 : length(power)
                write(app.daq_Beam,voltage(k))
                pause(0.002)
                power(k) = read(app.daq_Beam,'OutputFormat','Matrix'); 
            end
            power = power / max(power); 
            app.calData = [power,voltage];
            app.update
            
            write(app.daq_Shutter,0);
            fprintf('Done!\n')
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            calibrationData = app.calData;
            uisave('calibrationData',app.path_CalData); 
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if ~isempty(app.path_CalData)
                [file,path] = uigetfile(app.path_CalData);
            else
                [file,path] = uigetfile('.mat');
            end
            if ~file
                return
            end
            app.path_CalData = [path,file]; 
            app.calData = struct2cell(load(app.path_CalData)); 
            app.calData = app.calData{1}; 
            
        end

        % Button pushed function: ShowCurveButton
        function ShowCurveButtonPushed(app, event)
            figure('Name','BEAM MODULATION CURVE','NumberTitle','off')
            plot(app.calData(:,2),app.calData(:,1));
            xlabel('Modulation(Volt)')
            ylabel('Power')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.POWERCONTROLSUIFigure = uifigure('Visible', 'off');
            app.POWERCONTROLSUIFigure.Name = 'POWER CONTROLS';
            app.POWERCONTROLSUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.SelectedBeamLabel = uilabel(app.POWERCONTROLSUIFigure);
            app.SelectedBeamLabel.Text = 'Selected Beam';
            app.PowerSlider = uislider(app.POWERCONTROLSUIFigure);
            app.PowerSlider.MajorTicks = [];
            app.PowerSlider.ValueChangedFcn = createCallbackFcn(app, @PowerSliderValueChanged, true);
            app.PowerSlider.MinorTicks = [];
            app.Label = uilabel(app.POWERCONTROLSUIFigure);
            app.Label.Text = '%';
            app.PowerEditField = uieditfield(app.POWERCONTROLSUIFigure, 'numeric');
            app.PowerEditField.Limits = [0 100];
            app.PowerEditField.ValueChangedFcn = createCallbackFcn(app, @PowerEditFieldValueChanged, true);
            app.PowerEditField.Value = 1;
            app.CalibrateButton = uibutton(app.POWERCONTROLSUIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.LoadButton = uibutton(app.POWERCONTROLSUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.POWERCONTROLSUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ShowCurveButton = uibutton(app.POWERCONTROLSUIFigure, 'push');
            app.ShowCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCurveButtonPushed, true);
            app.ShowCurveButton.Text = 'Show Curve';
            % Show the figure after all components are created
            app.POWERCONTROLSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = powerControlsV4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.POWERCONTROLSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.POWERCONTROLSUIFigure)
        end
    end
end