classdef stimulationControlsV4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        STIMULATIONCONTROLUIFigure  matlab.ui.Figure
        StartButton                 matlab.ui.control.StateButton
        StimulateLamp               matlab.ui.control.Lamp
        StimulateCheckBox           matlab.ui.control.CheckBox
        DeleteStimulationButton     matlab.ui.control.Button
        AddStimulationButton        matlab.ui.control.Button
        StimulationTable            matlab.ui.control.Table
    end

    
    properties (Access = public)
        % Handle
        hPSI    % PolyScanImage
        
        % Data acquisition
        DAQ_Stimulate   % NI-DAQ channel to output digital stimulation signal
        
        % Timer
        timer_Stimulate     % Timer for output stimulation signal
        timerTicToc
        
        % Selected stimulation
        selectedStimulation
        
        % Remaining output data
        remainOutput
        
    end
    
    methods (Access = public)
        % Update parameters in this app
        function update(app)
            % Check stimulation table
            data = app.StimulationTable.Data; 
            data = round(data*100)/100; 
            data(data<0) = 0;
            app.StimulationTable.Data = data;
            if isempty(data)||~isempty(find((data(:,2)-data(:,1))<0.0099,1))||~isempty(find(isinf(data),1))
                app.StimulateLamp.Color = [1,0,0]; 
                app.StimulateCheckBox.Value = 0; 
                app.StimulateLamp.Enable = 0; 
                app.StartButton.Enable = 0;
            else
                app.StimulateLamp.Color = [0,1,0]; 
                if app.StimulateCheckBox.Value
                    app.StimulateLamp.Enable = 1; 
                    app.StartButton.Enable = 1;
                else
                    app.StimulateLamp.Enable = 0;
                    app.StartButton.Enable = 0;
                end
            end
        end
        
        
        % Start stimulation
        function startStimulation(app)
            if ~app.StimulateCheckBox.Value
                return
            end
            % Prepare timer
            data = app.StimulationTable.Data;
            data = round(data);
            data(:,1) = data(:,1) + 1;
            outputData = zeros(max(data(:))+1,1);
            for k = 1 : size(data,1)
                outputData(data(k,1):data(k,2)) = 1;
            end
            app.remainOutput = outputData; 
            app.timer_Stimulate = timer('StartDelay',0,'Period',1,'ExecutionMode','fixedRate');
            app.timer_Stimulate.TasksToExecute = length(app.remainOutput)+1;
            app.timerTicToc = tic;
            app.timer_Stimulate.TimerFcn = @(~,~) app.stimulateTimerFcn;
            
            app.StartButton.Text = 'Stop';
            app.StartButton.Value = 1;
            app.timer_Stimulate.start;
        end
        
        % Stop stimulation
        function stopStimulation(app)
            app.StartButton.Text = 'Start';
            app.StartButton.Value = 0; 
            try
                app.timer_Stimulate.stop; 
            end
            write(app.DAQ_Stimulate,0);
        end
        
        % Execute remain output
        function stimulateTimerFcn(app)
            temp = toc(app.timerTicToc); temp = ceil(temp);
            if temp > length(app.remainOutput)
                app.stopStimulation
            else
                write(app.DAQ_Stimulate,app.remainOutput(temp));
            end
%             if isempty(app.remainOutput)
%                 app.stopStimulation
%             else
%                 write(app.DAQ_Stimulate,app.remainOutput(1));
%                 app.remainOutput(1) = [];
%             end
        end
        
        % Exit
        function exitHandle(app)
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hPSI)
            app.hPSI = hPSI; 
            app.STIMULATIONCONTROLUIFigure.Visible = 'off';
            run(app.hPSI.hMain.UserSettingsEditField.Value); 
            
            % Data acquisition
            fprintf('Connecting NI-DAQ Board for stimulation...');
            app.DAQ_Stimulate = daq('ni'); 
            addoutput(app.DAQ_Stimulate,stimulationDeviceName,stimulationChanID,'Digital');
            app.DAQ_Stimulate.Rate = 100;
            write(app.DAQ_Stimulate,0);
            
            fprintf('Done!\n');


        end

        % Close request function: STIMULATIONCONTROLUIFigure
        function closeRequest(app, event)
            app.STIMULATIONCONTROLUIFigure.Visible = 'off';
            
        end

        % Cell edit callback: StimulationTable
        function StimulationTableCellEdit(app, event)
            app.update
            
        end

        % Cell selection callback: StimulationTable
        function StimulationTableCellSelection(app, event)
            indices = event.Indices;
            app.selectedStimulation = indices(:,1);
            
        end

        % Button pushed function: AddStimulationButton
        function AddStimulationButtonPushed(app, event)
            app.StimulationTable.Data = cat(1,app.StimulationTable.Data,[0,inf]); 
            app.update
            
        end

        % Button pushed function: DeleteStimulationButton
        function DeleteStimulationButtonPushed(app, event)
            app.StimulationTable.Data(app.selectedStimulation,:) = [];
            app.selectedStimulation = [];
            app.update
            
        end

        % Value changed function: StimulateCheckBox
        function StimulateCheckBoxValueChanged(app, event)
            app.update
            
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            if app.StartButton.Value
                app.startStimulation;
            else
                app.stopStimulation;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.STIMULATIONCONTROLUIFigure = uifigure('Visible', 'off');
            app.STIMULATIONCONTROLUIFigure.Name = 'STIMULATION CONTROL';
            app.STIMULATIONCONTROLUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.StimulationTable = uitable(app.STIMULATIONCONTROLUIFigure);
            app.StimulationTable.ColumnName = {'Start (sec)'; 'End (sec)'};
            app.StimulationTable.RowName = {};
            app.StimulationTable.ColumnEditable = true;
            app.StimulationTable.CellEditCallback = createCallbackFcn(app, @StimulationTableCellEdit, true);
            app.StimulationTable.CellSelectionCallback = createCallbackFcn(app, @StimulationTableCellSelection, true);
            app.AddStimulationButton = uibutton(app.STIMULATIONCONTROLUIFigure, 'push');
            app.AddStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @AddStimulationButtonPushed, true);
            app.AddStimulationButton.Text = 'Add';
            app.DeleteStimulationButton = uibutton(app.STIMULATIONCONTROLUIFigure, 'push');
            app.DeleteStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStimulationButtonPushed, true);
            app.DeleteStimulationButton.Text = 'Delete';
            app.StimulateCheckBox = uicheckbox(app.STIMULATIONCONTROLUIFigure);
            app.StimulateCheckBox.ValueChangedFcn = createCallbackFcn(app, @StimulateCheckBoxValueChanged, true);
            app.StimulateCheckBox.Text = 'Stimulate';
            app.StimulateLamp = uilamp(app.STIMULATIONCONTROLUIFigure);
            app.StartButton = uibutton(app.STIMULATIONCONTROLUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.STIMULATIONCONTROLUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimulationControlsV4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.STIMULATIONCONTROLUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.STIMULATIONCONTROLUIFigure)
        end
    end
end