classdef wfmControlsV4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WAVEFORMCONTROLSUIFigure    matlab.ui.Figure
        BidirectionalYScanCheckBox  matlab.ui.control.CheckBox
        FastStageimagingCheckBox    matlab.ui.control.CheckBox
        VolumeimagingCheckBox       matlab.ui.control.CheckBox
        OutputWaveformsCheckBox     matlab.ui.control.CheckBox
        AODFrequencyAxes            matlab.ui.control.UIAxes
        ZPiezoAxes                  matlab.ui.control.UIAxes
        YGalvoAxes                  matlab.ui.control.UIAxes
        XGalvoAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Handle
        hPSI    % PolyScanImage
        
        % Data Acquisition
        daq_Wfm     % NI-DAQ channels to output waveforms
        
        % Waveform
        wfms            % Matrix contains all waveforms to output
    end
    
    methods (Access = public)
        
        % Update parameters in this app
        function update(app)
            run(app.hPSI.hMain.UserSettingsEditField.Value); 
            AODFreq = reshape(AODFreq,[1 length(AODFreq)]); 
            zoom = app.hPSI.hMain.ZoomDropDown.Value; 
            lineTriggersPerFrame = app.hPSI.hConfig.LinesPerFrameEditField.Value/zoom; 
            angularRange_YGalvo = (app.hPSI.hConfig.LinesPerFrameEditField.Value/app.hPSI.hConfig.PixelsPerLineEditField.Value)*polygonFacetAngularRange/zoom/polygonMagnificationX*yGalvoDenseSamplingRatio; 
            voltageRange_YGalvo = [-angularRange_YGalvo/2,angularRange_YGalvo/2] * galvoVoltsPerOpticalDegreeY; 
            
            % Waveforms when volume imaging and fast stage imaging disabled
            %   AOD frequency control
            wfm_AODFrequency = 0 * AODFreq; 
            wfm_AODFrequency(:) = repmat(AODFreq(length(AODFreq)/zoom:length(AODFreq)/zoom:end),[length(AODFreq)/zoom,1]); 
            wfm_AODFrequency = repmat(wfm_AODFrequency,[1 lineTriggersPerFrame]).'; 
            %   X galvo
            wfm_XGalvo = zeros(length(AODFreq)*lineTriggersPerFrame,1);
            %   Y galvo
            if app.BidirectionalYScanCheckBox.Value
                wfm_YGalvo = [linspace(voltageRange_YGalvo(1)/2,voltageRange_YGalvo(2)/2,lineTriggersPerFrame*length(AODFreq)/2), ...
                    linspace(voltageRange_YGalvo(2)/2,voltageRange_YGalvo(1)/2,lineTriggersPerFrame*length(AODFreq)/2)].'; 
            else
                wfm_YGalvo = [linspace(voltageRange_YGalvo(1),voltageRange_YGalvo(2),(lineTriggersPerFrame-galvoFlyback)*length(AODFreq)), ...
                    linspace(voltageRange_YGalvo(2),voltageRange_YGalvo(1),galvoFlyback*length(AODFreq))].'; 
            end
            
            %   Z Piezo
            wfm_ZPiezo = zeros(length(AODFreq)*lineTriggersPerFrame,1); 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % TODO: Edit here after volume control handle finished
            wfm_ZPiezo(2) = 5;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %   Line trigger output
            wfm_lineTrigger = 0 * AODFreq - 1; 
            wfm_lineTrigger(1) = 1; 
            wfm_lineTrigger = repmat(wfm_lineTrigger,[1 lineTriggersPerFrame]).';
            
            % Volume imaging or fast stage imaging enabled
            %   Volume imaging
            if app.VolumeimagingCheckBox.Value
                
            end
            %   Fast stage imaging
            if app.FastStageimagingCheckBox.Value
                WfmLength = length(wfm_AODFrequency);
                stayFrames = app.hPSI.hFastStage.StagePositionsTable.Data(:,4); 
                yGalvoScale = app.hPSI.hFastStage.StagePositionsTable.Data(:,5); 
                temp = [];
                for k = 1 : length(stayFrames)
                    temp = cat(1,temp,ones(stayFrames(k),1)*yGalvoScale(k));
                end
                yGalvoScale = temp;
                yGalvoScaleLength = length(yGalvoScale);
                wfm_AODFrequency = repmat(wfm_AODFrequency,[yGalvoScaleLength,1]);
                wfm_XGalvo = repmat(wfm_XGalvo,[yGalvoScaleLength,1]);
                wfm_YGalvo = repmat(wfm_YGalvo,[yGalvoScaleLength,1]);
                wfm_ZPiezo = repmat(wfm_ZPiezo,[yGalvoScaleLength,1]); 
                wfm_lineTrigger = repmat(wfm_lineTrigger,[yGalvoScaleLength,1]); 
                yGalvoScale = repmat(yGalvoScale.',[WfmLength,1]);
                yGalvoScale = reshape(yGalvoScale,[numel(yGalvoScale),1]);
                wfm_YGalvo = wfm_YGalvo.*yGalvoScale; wfm_YGalvo = [wfm_YGalvo;wfm_YGalvo(1)];
                for k = 1 : yGalvoScaleLength
                    temp = linspace(wfm_YGalvo((k*lineTriggersPerFrame-galvoFlyback)*length(AODFreq)),wfm_YGalvo(k*lineTriggersPerFrame*length(AODFreq)+1),galvoFlyback*length(AODFreq)).';
                    wfm_YGalvo(((k*lineTriggersPerFrame-galvoFlyback)*length(AODFreq)+1):(k*lineTriggersPerFrame*length(AODFreq))) = temp;
                end
                wfm_YGalvo(end) = [];
            end
            
            % X,Y Galvo offset
            wfm_YGalvo = wfm_YGalvo + yGalvoOffsetVoltage; 
            
            
            % Update waveform on each axes
            temp = wfm_AODFrequency(1:length(AODFreq)).'; temp = repmat(temp,[lineTriggersPerFrame,1]); temp = temp(:);
            plot(app.AODFrequencyAxes,temp); app.AODFrequencyAxes.XLim = [0,length(temp)]; 
            plot(app.XGalvoAxes,wfm_XGalvo); app.XGalvoAxes.XLim = [0,length(wfm_XGalvo)]; 
            plot(app.YGalvoAxes,wfm_YGalvo); app.YGalvoAxes.XLim = [0,length(wfm_YGalvo)]; 
            plot(app.ZPiezoAxes,wfm_ZPiezo); app.ZPiezoAxes.XLim = [0,length(wfm_ZPiezo)]; 
            
            % Repmat waveforms to be load to DAQ
            app.wfms = cat(2,wfm_AODFrequency,wfm_XGalvo,wfm_YGalvo,wfm_ZPiezo,wfm_lineTrigger);
            repeatNum = max(ceil(digitalClockRate/2/size(app.wfms,1)),1); 
            app.wfms = repmat(app.wfms,[repeatNum,1]);
        end
        
        % Start output waveform
        function startOutputWfm(app)
            app.OutputWaveformsCheckBox.Value = true;
            preload(app.daq_Wfm,app.wfms); 
            start(app.daq_Wfm,"RepeatOutput")
        end
        
        % Stop output waveform
        function stopOutputWfm(app)
            app.OutputWaveformsCheckBox.Value = false; 
            run(app.hPSI.hMain.UserSettingsEditField.Value); 
            stop(app.daq_Wfm)
            write(app.daq_Wfm,[0,app.wfms(1,2),app.wfms(1,3),-1/2*piezoTravelRange*piezoVoltsPerMicron,-1]); 
        end
        
        % Exit
        function exitHandle(app)
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hPSI)
            app.hPSI = hPSI; 
            app.WAVEFORMCONTROLSUIFigure.Visible = 'off';
            run(app.hPSI.hMain.UserSettingsEditField.Value); 
            
            % Data acquisition
            %   Waveforms
            fprintf('Connecting NI-DAQ Board for waveforms output...');
            app.daq_Wfm = daq('ni');
            addoutput(app.daq_Wfm,AODDeviceName,['ao',num2str(AODFreqAOChanID)],'Voltage');             % AOD frequency control
            addoutput(app.daq_Wfm,galvoDeviceName,['ao',num2str(galvoAOChanIDX)],'Voltage');            % X galvo control
            addoutput(app.daq_Wfm,galvoDeviceName,['ao',num2str(galvoAOChanIDY)],'Voltage');            % Y galvo control
            addoutput(app.daq_Wfm,piezoDeviceName,['ao',num2str(piezoChanID)],'Voltage');               % Piezo control
            addoutput(app.daq_Wfm,lineTriggerDeviceName,['ao',num2str(lineTriggerChanID)],'Voltage');   % Line trigger output to synchronize Alazar board
            addtrigger(app.daq_Wfm,'Digital','StartTrigger','External',digitalTriggerName);             % Start trigger of all output waveforms
            addclock(app.daq_Wfm,'ScanClock','External',digitalClockName);                              % External clock of all output waveforms
            app.daq_Wfm.Rate = digitalClockRate;                                                        % Frequency of the external clock rate
            fprintf('Done!\n')
            
            % Set plot y limit
            app.AODFrequencyAxes.YLim = AODFreqVoltageRange;
            app.XGalvoAxes.YLim = [-1/2,1/2]*xGalvoAngularRange*galvoVoltsPerOpticalDegreeX;
            app.YGalvoAxes.YLim = [-1/2,1/2]*yGalvoAngularRange*galvoVoltsPerOpticalDegreeY;
            app.ZPiezoAxes.YLim = [-1/2,1/2]*piezoTravelRange*piezoVoltsPerMicron;
        end

        % Close request function: WAVEFORMCONTROLSUIFigure
        function closeRequest(app, event)
            app.WAVEFORMCONTROLSUIFigure.Visible = 'off';
            
        end

        % Value changed function: OutputWaveformsCheckBox
        function OutputWaveformsCheckBoxValueChanged(app, event)
            if app.OutputWaveformsCheckBox.Value
                app.startOutputWfm
            else
                app.stopOutputWfm
            end
            
        end

        % Value changed function: VolumeimagingCheckBox
        function VolumeimagingCheckBoxValueChanged(app, event)
            value = app.VolumeimagingCheckBox.Value;
            
        end

        % Value changed function: FastStageimagingCheckBox
        function FastStageimagingCheckBoxValueChanged(app, event)
%             if app.FastStageimagingCheckBox.Value
%                 app.hPSI.hFastStage.FASTSTAGEIMAGINGUIFigure.Visible = 'on';
%             else
%                 app.hPSI.hFastStage.FASTSTAGEIMAGINGUIFigure.Visible = 'off';
%             end
            app.update
            
        end

        % Value changed function: BidirectionalYScanCheckBox
        function BidirectionalYScanCheckBoxValueChanged(app, event)
            app.update
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WAVEFORMCONTROLSUIFigure = uifigure('Visible', 'off');
            app.WAVEFORMCONTROLSUIFigure.Name = 'WAVEFORM CONTROLS';
            app.WAVEFORMCONTROLSUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.XGalvoAxes = uiaxes(app.WAVEFORMCONTROLSUIFigure);
            title(app.XGalvoAxes, 'X Galvo')
            app.XGalvoAxes.XTick = [];
            app.XGalvoAxes.YTick = [];
            app.YGalvoAxes = uiaxes(app.WAVEFORMCONTROLSUIFigure);
            title(app.YGalvoAxes, 'Y Galvo')
            app.YGalvoAxes.XTick = [];
            app.YGalvoAxes.YTick = [];
            app.ZPiezoAxes = uiaxes(app.WAVEFORMCONTROLSUIFigure);
            title(app.ZPiezoAxes, 'Z Piezo')
            app.ZPiezoAxes.XTick = [];
            app.ZPiezoAxes.YTick = [];
            app.AODFrequencyAxes = uiaxes(app.WAVEFORMCONTROLSUIFigure);
            title(app.AODFrequencyAxes, 'AOD Frequency')
            app.AODFrequencyAxes.XTick = [];
            app.AODFrequencyAxes.YTick = [];
            app.OutputWaveformsCheckBox = uicheckbox(app.WAVEFORMCONTROLSUIFigure);
            app.OutputWaveformsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputWaveformsCheckBoxValueChanged, true);
            app.OutputWaveformsCheckBox.Text = 'Output Waveforms';
            app.VolumeimagingCheckBox = uicheckbox(app.WAVEFORMCONTROLSUIFigure);
            app.VolumeimagingCheckBox.ValueChangedFcn = createCallbackFcn(app, @VolumeimagingCheckBoxValueChanged, true);
            app.VolumeimagingCheckBox.Text = 'Volume imaging';
            app.FastStageimagingCheckBox = uicheckbox(app.WAVEFORMCONTROLSUIFigure);
            app.FastStageimagingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FastStageimagingCheckBoxValueChanged, true);
            app.FastStageimagingCheckBox.Text = 'Fast Stage imaging';
            app.BidirectionalYScanCheckBox = uicheckbox(app.WAVEFORMCONTROLSUIFigure);
            app.BidirectionalYScanCheckBox.ValueChangedFcn = createCallbackFcn(app, @BidirectionalYScanCheckBoxValueChanged, true);
            app.BidirectionalYScanCheckBox.Text = 'Bidirectional Y Scan';
            % Show the figure after all components are created
            app.WAVEFORMCONTROLSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wfmControlsV4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WAVEFORMCONTROLSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WAVEFORMCONTROLSUIFigure)
        end
    end
end